classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        qu        matlab.ui.control.Button
        Label     matlab.ui.control.Label
        yuezhi    matlab.ui.control.NumericEditField
        Label_2   matlab.ui.control.Label
        quchuzhi  matlab.ui.control.NumericEditField
        cun       matlab.ui.control.Button
    end

    
    properties (Access = private)
        balance double % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.balance = 1000;
            app.quchuzhi.Value = 0;
            app.yuezhi.Value = app.balance;
        end

        % Button pushed function: qu
        function quButtonPushed(app, event)
            value = app.quchuzhi.Value ;
            app.balance = app.balance - value ;
            app.yuezhi.Value = app.balance ;
        end

        % Button pushed function: cun
        function cunButtonPushed(app, event)
            value = app.quchuzhi.Value ;
            app.balance = app.balance + value ;
            app.yuezhi.Value = app.balance ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.qu = uibutton(app.UIFigure, 'push');
            app.qu.ButtonPushedFcn = createCallbackFcn(app, @quButtonPushed, true);
            app.qu.Text = '取出';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '余额';
            app.yuezhi = uieditfield(app.UIFigure, 'numeric');
            app.yuezhi.Limits = [0 Inf];
            app.yuezhi.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '取出额';
            app.quchuzhi = uieditfield(app.UIFigure, 'numeric');
            app.cun = uibutton(app.UIFigure, 'push');
            app.cun.ButtonPushedFcn = createCallbackFcn(app, @cunButtonPushed, true);
            app.cun.Text = '存入';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end